classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ProiectGITHubButton          matlab.ui.control.Button
        DocumentatieButton           matlab.ui.control.Button
        PrezentareButton             matlab.ui.control.Button
        DutyCicleEditField           matlab.ui.control.NumericEditField
        DutyCicleEditFieldLabel      matlab.ui.control.Label
        AmplitudineVEditField        matlab.ui.control.NumericEditField
        AmplitudineVEditFieldLabel   matlab.ui.control.Label
        FrecventaHzEditField         matlab.ui.control.NumericEditField
        FrecventaHzEditFieldLabel    matlab.ui.control.Label
        GenereazaPWMButton           matlab.ui.control.Button
        ControlSwitch                matlab.ui.control.ToggleSwitch
        ControlSwitchLabel           matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        TensiunedeintrareVEditField  matlab.ui.control.NumericEditField
        TensiunedeintrareVEditFieldLabel  matlab.ui.control.Label
        CnFEditField                 matlab.ui.control.NumericEditField
        CnFEditFieldLabel            matlab.ui.control.Label
        P2OhmEditField               matlab.ui.control.NumericEditField
        P2OhmEditFieldLabel          matlab.ui.control.Label
        P1OhmEditField               matlab.ui.control.NumericEditField
        P1OhmEditFieldLabel          matlab.ui.control.Label
        LowtimemsKnob                matlab.ui.control.Knob
        LowtimemsKnobLabel           matlab.ui.control.Label
        HighTimemsKnob               matlab.ui.control.Knob
        HighTimemsKnobLabel          matlab.ui.control.Label
        AmplitudeVKnob               matlab.ui.control.Knob
        OnOffSwitch                  matlab.ui.control.RockerSwitch
        OnOffSwitchLabel             matlab.ui.control.Label
        AmplitudeVKnobLabel          matlab.ui.control.Label
        StateLamp                    matlab.ui.control.Lamp
        UIAxes                       matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: OnOffSwitch
        function OnOffSwitchValueChanged(app, event)
            % Ia valoarea lui TurnOnSwitch
            stateOfOnSwitch = app.OnOffSwitch.Value;
            % Daca valoarea lui TurnOnSwitch este setata pe 'On' atunci
            % seteaza lampa la culoarea verde si incepe programul
            % Daca este pe 'Off' seteaza culoarea lampii pe rosu si opreste
            % programul
            switch stateOfOnSwitch
                case 'On'
                    app.StateLamp.Color = 'g';
                    stateOfControlSwitch = app.ControlSwitch.Value;
                    switch stateOfControlSwitch
                        case 'Auto'
                            app.AmplitudeVKnob.Enable = true;
                            app.HighTimemsKnob.Enable = true;
                            app.LowtimemsKnob.Enable = true;

                            app.P1OhmEditField.Enable = false;
                            app.P2OhmEditField.Enable = false;
                            app.TensiunedeintrareVEditField.Enable = false;
                            app.CnFEditField.Enable = false;
                            
                            app.GenereazaPWMButton.Enable = false;
                            app.CnFEditField.Value = 1;
                        case 'Manual'
                            app.AmplitudeVKnob.Enable = false;
                            app.HighTimemsKnob.Enable = false;
                            app.LowtimemsKnob.Enable = false;

                            app.P1OhmEditField.Enable = true;
                            app.P2OhmEditField.Enable = true;
                            app.TensiunedeintrareVEditField.Enable = true;
                            app.CnFEditField.Enable = true;
                            
                            app.GenereazaPWMButton.Enable = true;
                        otherwise
                            DISP ('Invalit control switch state');
                    end
                    highTime = app.HighTimemsKnob.Value * 0.001;
                    lowTime = app.LowtimemsKnob.Value * 0.001;
                    t = linspace(0,2*pi,10000);
                    amplitude = app.AmplitudeVKnob.Value;
                    app.AmplitudineVEditField.Value = amplitude;


                    dutyCicle = (highTime/(highTime+lowTime)) * 100;
                    app.DutyCicleEditField.Value = dutyCicle;
                    pwmSignal = amplitude * square(t,dutyCicle);
                    plot(app.UIAxes,pwmSignal);

                    freq = 1/(highTime+lowTime);
                    app.FrecventaHzEditField.Value = freq;

                    p1 = 10000000*highTime/0.693;
                    app.P1OhmEditField.Value = p1;

                    p2 = 10000000*lowTime/0.693;
                    app.P2OhmEditField.Value = p2;

                    app.TensiunedeintrareVEditField.Value = amplitude;
                    
                case 'Off'
                    app.StateLamp.Color = 'r';
                    app.AmplitudeVKnob.Enable = false;
                    app.HighTimemsKnob.Enable = false;
                    app.LowtimemsKnob.Enable = false;

                    app.P1OhmEditField.Enable = false;
                    app.P2OhmEditField.Enable = false;
                    app.TensiunedeintrareVEditField.Enable = false;
                    app.CnFEditField.Enable = false;
                    
                    app.GenereazaPWMButton.Enable = false;
                otherwise
                    DISP('Turn on switch error');
            end
        end

        % Value changed function: AmplitudeVKnob
        function AmplitudeVKnobValueChanged(app, event)
            t = linspace(0,2*pi,10000);
            amplitude = app.AmplitudeVKnob.Value;
            app.AmplitudineVEditField.Value = amplitude;
            highTime = app.HighTimemsKnob.Value * 0.001;
            lowTime = app.LowtimemsKnob.Value * 0.001;

            dutyCicle = (highTime/(highTime+lowTime)) * 100;
            app.DutyCicleEditField.Value = dutyCicle;
            pwmSignal = amplitude * square(2*t,dutyCicle);
            plot(app.UIAxes,pwmSignal);

            freq = 1/(highTime+lowTime);
            app.FrecventaHzEditField.Value = freq;

            p1 = 10000000*highTime/0.693;
            app.P1OhmEditField.Value = p1;

            p2 = 10000000*lowTime/0.693;
            app.P2OhmEditField.Value = p2;

            app.TensiunedeintrareVEditField.Value = amplitude;
        end

        % Value changed function: ControlSwitch
        function ControlSwitchValueChanged(app, event)
            
            stateOfControlSwitch = app.ControlSwitch.Value;
            switch stateOfControlSwitch
                case 'Auto'
                    app.AmplitudeVKnob.Enable = true;
                    app.HighTimemsKnob.Enable = true;
                    app.LowtimemsKnob.Enable = true;

                    app.P1OhmEditField.Enable = false;
                    app.P2OhmEditField.Enable = false;
                    app.TensiunedeintrareVEditField.Enable = false;
                    app.CnFEditField.Enable = false;
                 
                    app.GenereazaPWMButton.Enable = false;
                case 'Manual'
                    app.AmplitudeVKnob.Enable = false;
                    app.HighTimemsKnob.Enable = false;
                    app.LowtimemsKnob.Enable = false;

                    app.P1OhmEditField.Enable = true;
                    app.P2OhmEditField.Enable = true;
                    app.TensiunedeintrareVEditField.Enable = true;
                    app.CnFEditField.Enable = true;
                    
                    app.GenereazaPWMButton.Enable = true;
                otherwise
                    DISP ('Invalid control switch state');
            end
        end

        % Value changed function: HighTimemsKnob
        function HighTimemsKnobValueChanged(app, event)

             t = linspace(0,2*pi,1000);
             amplitude = app.AmplitudeVKnob.Value;

             highTime = app.HighTimemsKnob.Value * 0.001;
             lowTime = app.LowtimemsKnob.Value * 0.001;

             dutyCicle = (highTime/(highTime+lowTime)) * 100;
             app.DutyCicleEditField.Value = dutyCicle;
             pwmSignal = amplitude * square(2*t,dutyCicle);
             plot(app.UIAxes,pwmSignal);
             
             freq = 1/(highTime+lowTime);
             app.FrecventaHzEditField.Value = freq;

             p1 = 10000000*highTime/0.693;
             app.P1OhmEditField.Value = p1;

             p2 = 10000000*lowTime/0.693;
             app.P2OhmEditField.Value = p2;

             app.TensiunedeintrareVEditField.Value = amplitude;
             
        end

        % Value changed function: LowtimemsKnob
        function LowtimemsKnobValueChanged(app, event)

            t = linspace(0,2*pi,10000);
            amplitude = app.AmplitudeVKnob.Value;

            highTime = app.HighTimemsKnob.Value * 0.001;
            lowTime = app.LowtimemsKnob.Value * 0.001;

            dutyCicle = (highTime/(highTime+lowTime)) * 100;
            app.DutyCicleEditField.Value = dutyCicle;
            pwmSignal = amplitude * square(2*t,dutyCicle);
            plot(app.UIAxes,pwmSignal);
               
            freq = 1/(highTime+lowTime);
            app.FrecventaHzEditField.Value = freq;

            p1 = 10000000*highTime/0.693;
            app.P1OhmEditField.Value = p1;

            p2 = 10000000*lowTime/0.693;
            app.P2OhmEditField.Value = p2;

            app.TensiunedeintrareVEditField.Value = amplitude;
            
        end

        % Value changing function: AmplitudeVKnob
        function AmplitudeVKnobValueChanging(app, event)
            t = linspace(0,2*pi,10000);
            amplitude = event.Value;
            app.AmplitudineVEditField.Value = amplitude;

            highTime = app.HighTimemsKnob.Value * 0.001;
            lowTime = app.LowtimemsKnob.Value * 0.001;

            dutyCicle = (highTime/(highTime+lowTime)) * 100;
            app.DutyCicleEditField.Value = dutyCicle;
            pwmSignal = amplitude * square(2*t,dutyCicle);
            plot(app.UIAxes,pwmSignal);

           freq = 1/(highTime+lowTime);
           app.FrecventaHzEditField.Value = freq;

           p1 = 10000000*highTime/0.693;
           app.P1OhmEditField.Value = p1;

           p2 = 10000000*lowTime/0.693;
           app.P2OhmEditField.Value = p2;

           app.TensiunedeintrareVEditField.Value = amplitude;
            
        end

        % Value changing function: HighTimemsKnob
        function HighTimemsKnobValueChanging(app, event)
            
            t = linspace(0,2*pi,1000);
            amplitude = app.AmplitudeVKnob.Value;

            highTime = event.Value * 0.001;
            lowTime = app.LowtimemsKnob.Value * 0.001;

            dutyCicle = (highTime/(highTime+lowTime)) * 100;
            app.DutyCicleEditField.Value = dutyCicle;
            pwmSignal = amplitude * square(2*t,dutyCicle);
            plot(app.UIAxes,pwmSignal);

            freq = 1/(highTime+lowTime);
            app.FrecventaHzEditField.Value = freq;

            p1 = 10000000*highTime/0.693;
            app.P1OhmEditField.Value = p1;

            p2 = 10000000*lowTime/0.693;
            app.P2OhmEditField.Value = p2;

            app.TensiunedeintrareVEditField.Value = amplitude;
          
        end

        % Value changing function: LowtimemsKnob
        function LowtimemsKnobValueChanging(app, event)

            t = linspace(0,2*pi,10000);
            amplitude = app.AmplitudeVKnob.Value;

            highTime = app.HighTimemsKnob.Value * 0.001;
            lowTime = event.Value * 0.001;

            dutyCicle = (highTime/(highTime+lowTime)) * 100;
            app.DutyCicleEditField.Value = dutyCicle;
            pwmSignal = amplitude * square(2*t,dutyCicle);
            plot(app.UIAxes,pwmSignal);

            freq = 1/(highTime+lowTime);
            app.FrecventaHzEditField.Value = freq;

            p1 = 10000000*highTime/0.693;
            app.P1OhmEditField.Value = p1;

            p2 = 10000000*lowTime/0.693;
            app.P2OhmEditField.Value = p2;

            app.TensiunedeintrareVEditField.Value = amplitude;
        end

        % Button pushed function: GenereazaPWMButton
        function GenereazaPWMButtonPushed(app, event)
            t = linspace(0,2*pi,10000);
            amplitude = app.AmplitudineVEditField.Value;

            highTime = 0.693*((app.P1OhmEditField.Value/1000)*(app.CnFEditField.Value*1000000000));
            lowTime = 0.693*((app.P2OhmEditField.Value/1000)*(app.CnFEditField.Value*1000000000));
            
            dutyCicle = (highTime/(highTime+lowTime)) * 100;
            app.DutyCicleEditField.Value = dutyCicle;
            pwmSignal = amplitude * square(2*t,dutyCicle);
            plot(app.UIAxes,pwmSignal);

            freq = 1/(highTime+lowTime);
            app.FrecventaHzEditField.Value = freq;

            
        end

        % Button pushed function: DocumentatieButton
        function DocumentatieButtonPushed(app, event)
            open("documentatie.pdf");
        end

        % Button pushed function: PrezentareButton
        function PrezentareButtonPushed(app, event)
            open("prezentare.pptx");
        end

        % Button pushed function: ProiectGITHubButton
        function ProiectGITHubButtonPushed(app, event)
            web('http://www.mathworks.com', '-browser')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Iesire')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.StateLamp = uilamp(app.UIFigure);
            app.AmplitudeVKnobLabel = uilabel(app.UIFigure);
            app.AmplitudeVKnobLabel.Text = 'Amplitude (V)';
            app.OnOffSwitchLabel = uilabel(app.UIFigure);
            app.OnOffSwitchLabel.Text = 'On/Off';
            app.OnOffSwitch = uiswitch(app.UIFigure, 'rocker');
            app.OnOffSwitch.ValueChangedFcn = createCallbackFcn(app, @OnOffSwitchValueChanged, true);
            app.AmplitudeVKnob = uiknob(app.UIFigure, 'continuous');
            app.AmplitudeVKnob.Limits = [5 12];
            app.AmplitudeVKnob.ValueChangedFcn = createCallbackFcn(app, @AmplitudeVKnobValueChanged, true);
            app.AmplitudeVKnob.ValueChangingFcn = createCallbackFcn(app, @AmplitudeVKnobValueChanging, true);
            app.AmplitudeVKnob.Value = 5;
            app.HighTimemsKnobLabel = uilabel(app.UIFigure);
            app.HighTimemsKnobLabel.Text = {'High Time (ms)'; ''};
            app.HighTimemsKnob = uiknob(app.UIFigure, 'continuous');
            app.HighTimemsKnob.Limits = [1 700];
            app.HighTimemsKnob.MajorTicks = [1 100 200 300 400 500 600 700];
            app.HighTimemsKnob.ValueChangedFcn = createCallbackFcn(app, @HighTimemsKnobValueChanged, true);
            app.HighTimemsKnob.ValueChangingFcn = createCallbackFcn(app, @HighTimemsKnobValueChanging, true);
            app.HighTimemsKnob.Value = 1;
            app.LowtimemsKnobLabel = uilabel(app.UIFigure);
            app.LowtimemsKnobLabel.Text = {'Low time (ms)'; ''};
            app.LowtimemsKnob = uiknob(app.UIFigure, 'continuous');
            app.LowtimemsKnob.Limits = [1 700];
            app.LowtimemsKnob.MajorTicks = [1 100 200 300 400 500 600 700];
            app.LowtimemsKnob.ValueChangedFcn = createCallbackFcn(app, @LowtimemsKnobValueChanged, true);
            app.LowtimemsKnob.ValueChangingFcn = createCallbackFcn(app, @LowtimemsKnobValueChanging, true);
            app.LowtimemsKnob.MinorTicks = [1 21 41 61 81 101 121 141 161 181 201 221 241 261 281 301 321 341 361 381 400 420 440 460 480 500 520 540 560 580 600 620 640 660 680 700];
            app.LowtimemsKnob.Value = 1;
            app.P1OhmEditFieldLabel = uilabel(app.UIFigure);
            app.P1OhmEditFieldLabel.Text = 'P1 (Ohm)';
            app.P1OhmEditField = uieditfield(app.UIFigure, 'numeric');
            app.P1OhmEditField.Limits = [1 100000000000000];
            app.P1OhmEditField.Value = 1;
            app.P2OhmEditFieldLabel = uilabel(app.UIFigure);
            app.P2OhmEditFieldLabel.Text = 'P2 (Ohm)';
            app.P2OhmEditField = uieditfield(app.UIFigure, 'numeric');
            app.P2OhmEditField.Limits = [1 100000000000000];
            app.P2OhmEditField.Value = 1;
            app.CnFEditFieldLabel = uilabel(app.UIFigure);
            app.CnFEditFieldLabel.Text = 'C (nF)';
            app.CnFEditField = uieditfield(app.UIFigure, 'numeric');
            app.CnFEditField.Limits = [0.1 1000];
            app.CnFEditField.Value = 1;
            app.TensiunedeintrareVEditFieldLabel = uilabel(app.UIFigure);
            app.TensiunedeintrareVEditFieldLabel.Text = 'Tensiune de intrare (V)';
            app.TensiunedeintrareVEditField = uieditfield(app.UIFigure, 'numeric');
            app.TensiunedeintrareVEditField.Limits = [5 12];
            app.TensiunedeintrareVEditField.Value = 5;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Schematic.PNG';
            app.ControlSwitchLabel = uilabel(app.UIFigure);
            app.ControlSwitchLabel.Text = 'Control';
            app.ControlSwitch = uiswitch(app.UIFigure, 'toggle');
            app.ControlSwitch.Items = {'Auto', 'Manual'};
            app.ControlSwitch.ValueChangedFcn = createCallbackFcn(app, @ControlSwitchValueChanged, true);
            app.ControlSwitch.Value = 'Auto';
            app.GenereazaPWMButton = uibutton(app.UIFigure, 'push');
            app.GenereazaPWMButton.ButtonPushedFcn = createCallbackFcn(app, @GenereazaPWMButtonPushed, true);
            app.GenereazaPWMButton.Text = 'Genereaza PWM';
            app.FrecventaHzEditFieldLabel = uilabel(app.UIFigure);
            app.FrecventaHzEditFieldLabel.Text = 'Frecventa (Hz)';
            app.FrecventaHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudineVEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudineVEditFieldLabel.Text = 'Amplitudine (V)';
            app.AmplitudineVEditField = uieditfield(app.UIFigure, 'numeric');
            app.DutyCicleEditFieldLabel = uilabel(app.UIFigure);
            app.DutyCicleEditFieldLabel.Text = 'DutyCicle (%)';
            app.DutyCicleEditField = uieditfield(app.UIFigure, 'numeric');
            app.PrezentareButton = uibutton(app.UIFigure, 'push');
            app.PrezentareButton.ButtonPushedFcn = createCallbackFcn(app, @PrezentareButtonPushed, true);
            app.PrezentareButton.Text = 'Prezentare';
            app.DocumentatieButton = uibutton(app.UIFigure, 'push');
            app.DocumentatieButton.ButtonPushedFcn = createCallbackFcn(app, @DocumentatieButtonPushed, true);
            app.DocumentatieButton.Text = 'Documentatie';
            app.ProiectGITHubButton = uibutton(app.UIFigure, 'push');
            app.ProiectGITHubButton.ButtonPushedFcn = createCallbackFcn(app, @ProiectGITHubButtonPushed, true);
            app.ProiectGITHubButton.Text = 'Proiect GITHub';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end